classdef TMVApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Tree                          matlab.ui.container.Tree
        InnerHHSNode                  matlab.ui.container.TreeNode
        VerificationNode              matlab.ui.container.TreeNode
        BlueLightNode                 matlab.ui.container.TreeNode
        PETNode                       matlab.ui.container.TreeNode
        DimensionalNode               matlab.ui.container.TreeNode
        PETOverhangNode               matlab.ui.container.TreeNode
        DistalTackNode                matlab.ui.container.TreeNode
        VisualNode                    matlab.ui.container.TreeNode
        PoorTackBondNode              matlab.ui.container.TreeNode
        TipAttachNode                 matlab.ui.container.TreeNode
        HHSE1DistanceNode             matlab.ui.container.TreeNode
        TipShapeNode                  matlab.ui.container.TreeNode
        XDimensionalNode              matlab.ui.container.TreeNode
        MBDistalTipNode               matlab.ui.container.TreeNode
        MarkerbandBubbleNode          matlab.ui.container.TreeNode
        XVisualNode                   matlab.ui.container.TreeNode
        TipLaminationNode             matlab.ui.container.TreeNode
        IMBondNode                    matlab.ui.container.TreeNode
        VerificationNode_2            matlab.ui.container.TreeNode
        CorrectBondaidIMBond          matlab.ui.container.TreeNode
        EXJBondNode                   matlab.ui.container.TreeNode
        VerificationNode_3            matlab.ui.container.TreeNode
        CorrectBondaidEXJBond         matlab.ui.container.TreeNode
        CorrectInnerMemberLengthNode  matlab.ui.container.TreeNode
        HoopingNode                   matlab.ui.container.TreeNode
        PatencyNode                   matlab.ui.container.TreeNode
        IMShaftNode                   matlab.ui.container.TreeNode
        VisualNode_2                  matlab.ui.container.TreeNode
        KinkedIMShaftNode             matlab.ui.container.TreeNode
        DistalSectionNode             matlab.ui.container.TreeNode
        VisualNode_3                  matlab.ui.container.TreeNode
        SurfaceImperfections          matlab.ui.container.TreeNode
        DimensionalNode_2             matlab.ui.container.TreeNode
        ElectrodeSpacingNode          matlab.ui.container.TreeNode
        FilletSeparationNode          matlab.ui.container.TreeNode
        FinalInspectNode              matlab.ui.container.TreeNode
        VisualImperfections           matlab.ui.container.TreeNode
        Station                       matlab.ui.container.Panel
        TMVChecklistPanel             matlab.ui.container.Panel
        GridLayout                    matlab.ui.container.GridLayout
        MSerialNumberInfo             matlab.ui.control.Button
        StepEditField                 matlab.ui.control.EditField
        IsthereaplannedchangetothisTestMethodLabel_5  matlab.ui.control.Label
        NamesEditField                matlab.ui.control.EditField
        IQReportEditField             matlab.ui.control.EditField
        IQDropDown                    matlab.ui.control.DropDown
        IsthereaplannedchangetothisTestMethodLabel_4  matlab.ui.control.Label
        IQReportNumberLabel           matlab.ui.control.Label
        CalibrationEditField          matlab.ui.control.EditField
        CalibrationNumberLabel        matlab.ui.control.Label
        CalibratedDropDown            matlab.ui.control.DropDown
        IsthereaplannedchangetothisTestMethodLabel_3  matlab.ui.control.Label
        MSerialEditField              matlab.ui.control.EditField
        IsthereaplannedchangetothisTestMethodLabel_2  matlab.ui.control.Label
        MNumberSerialNumberLabel      matlab.ui.control.Label
        PlannedChangeDropDown         matlab.ui.control.DropDown
        IsthereaplannedchangetothisTestMethodLabel  matlab.ui.control.Label
        WhatistheplannedchangeLabel   matlab.ui.control.Label
        PlannedChangeEditField        matlab.ui.control.EditField
        StepLabel                     matlab.ui.control.Label
        RevLabel                      matlab.ui.control.Label
        OPNumberLabel                 matlab.ui.control.Label
        OPEditField                   matlab.ui.control.EditField
        RevEditField                  matlab.ui.control.EditField
    end

    properties (Access = private)
        Data % TMV Data - Stores the data read from the Excel file
    end

    methods (Access = public)
        function enableForm(app)
            % Enable all form elements for user interaction
            setFormFields(app, 'on');
        end

        function disableForm(app)
            % Disable all form elements to prevent user interaction
            setFormFields(app, 'off');
        end

        function setFormFields(app, state)
            % Set the enable state of form fields based on the provided state
            fields = {app.OPEditField, app.RevEditField, app.StepEditField, ...
                app.PlannedChangeDropDown, app.PlannedChangeEditField, ...
                app.MSerialEditField, app.CalibratedDropDown, ...
                app.CalibrationEditField, app.IQDropDown, ...
                app.IQReportEditField, app.NamesEditField};

            % Loop through each field and set its enable state
            for i = 1:length(fields)
                fields{i}.Enable = state; % Set the enable property of each field
            end
        end
    end

    methods (Access = private)

        function writeFullExcel(app, filePath)
            % Read original row 1 (metadata headers)
            topRow = readcell(filePath, 'Sheet', 1, 'Range', 'A1:Q1');

            % Convert variable names (row 2)
            varNamesRow = app.Data.Properties.VariableNames;
            varNamesRow = cellstr(varNamesRow);

            % Convert table back to cell array (row 3 onwards)
            dataBody = table2cell(app.Data);

            % Combine all parts
            finalData = [topRow; varNamesRow; dataBody];

            % Write combined cell array
            writecell(finalData, filePath, 'Sheet', 1, 'Range', 'A1');
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set the name of the UI figure
            app.UIFigure.Name = 'Test Method Validation Checklist';

            % Read from Excel using readcell for flexible mixed-type input
            rawData = readcell('TMVMatlab.xlsx', 'Sheet', 1, 'Range', 'A1:Q13');

            % Use second row as header
            headerRow = rawData(2, :);

            % Replace empty headers with defaults, safely convert to strings
            for i = 1:numel(headerRow)
                val = headerRow{i};
                if isempty(val) || (ischar(val) && all(isspace(val))) || (isnumeric(val) && isnan(val))
                    headerRow{i} = sprintf('Column_%d', i);  % Default name
                else
                    headerRow{i} = string(val);
                end
            end

            % Clean and make headers valid
            headerRow = matlab.lang.makeValidName(string(headerRow));
            headerRow = matlab.lang.makeUniqueStrings(headerRow);
            rowLabels = rawData(3:end, 1);
            % Extract actual data starting from row 3
            dataRows = rawData(3:end, :);
            app.Data = cell2table(dataRows, 'VariableNames', headerRow);
            for col = 1:width(app.Data)
                app.Data.(col) = cellstr(string(app.Data.(col)));
            end

            % Initialize dropdown values to 'N/A'
            dropdowns = {app.PlannedChangeDropDown, app.CalibratedDropDown, app.IQDropDown};
            for i = 1:length(dropdowns)
                dropdowns{i}.Value = 'N/A';
            end

            % Assign column indices to UserData properties for easy reference
            nodeNames = {'BlueLightNode', 'PETOverhangNode', 'PoorTackBondNode', 'HHSE1DistanceNode', ...
                'TipLaminationNode', 'MarkerbandBubbleNode', 'MBDistalTipNode', ...
                'CorrectBondaidIMBond', 'CorrectBondaidEXJBond', 'CorrectInnerMemberLengthNode', ...
                'PatencyNode', 'KinkedIMShaftNode', 'SurfaceImperfections', ...
                'ElectrodeSpacingNode', 'FilletSeparationNode', 'VisualImperfections'};
            for i = 1:length(nodeNames)
                app.(nodeNames{i}).UserData = i + 1; % Since the first column is the Checklist
            end
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
           selectedNode = app.Tree.SelectedNodes;

            if isempty(selectedNode)
                disableForm(app); % Disable form if no node is selected
                return;
            end

            % Define worksteps
            Worksteps = {'Blue Light', 'PET Overhang', 'Poor Tack Bond', 'HHS – E1 Distance', 'MB-Distal Tip', 'Markerband Bubble', 'Tip Lamination', 'Correct Bondaid', 'Correct Bondaid', 'Correct Inner Member Length', 'Patency', 'IM Shaft-Visual', 'Visual-Surface Imperfections', 'Fillet Separation'};

            % Check if the selected node is a workstep
            if ismember(selectedNode.Text, Worksteps)
                enableForm(app);
            else
                disableForm(app);
            end

            % Ensure text boxes effected by drop downs remain disabled
            app.PlannedChangeEditField.Enable = 'off'; 
            app.CalibrationEditField.Enable = 'off';
            app.IQReportEditField.Enable = 'off';
            app.MSerialEditField.Enable = 'off';
            app.NamesEditField.Enable = 'off';

            % Update fields with workstep data if the selected node has no children
            if isempty(selectedNode.Children)
                % Use UserData to get the row index directly
                rowIndex = selectedNode.UserData; % Ensure UserData is set when creating nodes

                if ~isempty(app.Data) && any(rowIndex > 0) && all(rowIndex <= height(app.Data))
                    fields = {'OP Number', 'Rev', 'Step', ...
                        'IsThereAPlannedChangeToTheTestMethodInFutureOPUpdates', ...
                        'WhatIsThePlannedChange', 'M_SerialNumber', ...
                        'HaveTheseEquipment_FixturesBeenCalibrated', ...
                        'WhatHasBeenCalibrated', 'HaveTheseFixturesBeenQualified', ...
                        'IQReportNumber', 'WhichOperatorsHaveBeenTrained'};

                    % Define edit fields (without dropdowns)
                    editFields = {app.OPEditField, app.RevEditField, app.StepEditField, ...
                        app.PlannedChangeEditField, app.MSerialEditField, app.CalibrationEditField, ...
                        app.IQReportEditField, app.NamesEditField};

                    % Define editable dropdowns
                    editDropDowns = {app.PlannedChangeDropDown, app.CalibratedDropDown, app.IQDropDown};

                    % Update edit fields
                    for i = 1:length(fields)
                        if ismember(fields{i}, app.Data.Properties.VariableNames)
                            editFields{i}.Value = app.Data{rowIndex, fields{i}};
                            editDropDowns{i}.Value = app.Data{rowIndex, fields{length(editFields) + i}};
                        else
                            editFields{i}.Value = ''; % Default value if field not found
                            editDropDowns{i}.Value = 'N/A'; % Default value if field not found
                        end
                    end
                end
            end
        end

        % Value changed function: OPEditField
        function OPEditFieldValueChanged(app, event)
            % Get the input text from the OPEditField
            inputText = app.OPEditField.Value;

            % Normalize to string
            if iscell(inputText)
                inputText = inputText{1};
            elseif isstring(inputText)
                inputText = char(inputText);
            end

            % Get the selected node from the tree to retrieve UserData
            selectedNode = app.Tree.SelectedNodes;
            if isempty(selectedNode)
                return;
            end

            % Specify row and column
            rowIndex = 1;
            columnIndex = selectedNode.UserData;

            % Ensure column is a cell column to allow string input
            if ~iscell(app.Data{:, columnIndex})
                app.Data{:, columnIndex} = cellstr(string(app.Data{:, columnIndex}));
            end

            % Store the string as a cell entry
            app.Data{rowIndex, columnIndex} = {inputText};

            % Save the updated table back to Excel
            writeFullExcel(app, 'TMVMatlab.xlsx');
        end

        % Value changed function: RevEditField
        function RevEditFieldValueChanged(app, event)
            % Get the input text from the Rev edit field
            inputText = app.RevEditField.Value;

            % Ensure inputText is a single string
            if iscell(inputText)
                inputText = inputText{1}; % Extract the first element if it's a cell
            elseif isstring(inputText)
                inputText = char(inputText); % Convert to character array if it's a string
            end

            % Get the selected node from the tree to retrieve UserData
            selectedNode = app.Tree.SelectedNodes;
            if isempty(selectedNode)
                return; % Exit if no node is selected
            end

            % Specify the row index and retrieve the column index from UserData
            rowIndex = 2;
            columnIndex = selectedNode.UserData;

            % Update the data table with the input text
            app.Data{rowIndex, columnIndex} = {inputText}; % Store inputText in the specified cell

            % Write the updated table to an Excel file
            writeFullExcel(app, 'TMVMatlab.xlsx'); % Save the data to Excel
        end

        % Value changed function: StepEditField
        function StepEditFieldValueChanged(app, event)
            % Get the input text from the Step edit field
            inputText = app.StepEditField.Value;

            % Ensure inputText is a single string
            if iscell(inputText)
                inputText = inputText{1}; % Extract the first element if it's a cell
            elseif isstring(inputText)
                inputText = char(inputText); % Convert to character array if it's a string
            end

            % Get the selected node from the tree to retrieve UserData
            selectedNode = app.Tree.SelectedNodes;
            if isempty(selectedNode)
                return; % Exit if no node is selected
            end

            % Specify the row index and retrieve the column index from UserData
            rowIndex = 3;
            columnIndex = selectedNode.UserData;

            % Update the data table with the input text
            app.Data{rowIndex, columnIndex} = {inputText}; % Store inputText in the specified cell

            % Write the updated table to an Excel file
            writeFullExcel(app, 'TMVMatlab.xlsx'); % Save the data to Excel
        end

        % Value changed function: PlannedChangeEditField
        function PlannedChangeEditFieldValueChanged(app, event)
            % Get the input text from the Planned Change edit field
            inputText = app.PlannedChangeEditField.Value;

            % Ensure inputText is a single string
            if iscell(inputText)
                inputText = inputText{1}; % Extract the first element if it's a cell
            elseif isstring(inputText)
                inputText = char(inputText); % Convert to character array if it's a string
            end

            % Get the selected node from the tree to retrieve UserData
            selectedNode = app.Tree.SelectedNodes;
            if isempty(selectedNode)
                return; % Exit if no node is selected
            end

            % Specify the row index and retrieve the column index from UserData
            rowIndex = 5;
            columnIndex = selectedNode.UserData;

            % Update the data table with the input text
            app.Data{rowIndex, columnIndex} = {inputText}; % Store inputText in the specified cell

            % Write the updated table to an Excel file
            writeFullExcel(app, 'TMVMatlab.xlsx'); % Save the data to Excel
        end

        % Value changed function: PlannedChangeDropDown
        function PlannedChangeDropDownValueChanged(app, event)
            % Get the selected value from the Planned Change dropdown
            selectedValue = app.PlannedChangeDropDown.Value;

            % Get the selected node from the tree to retrieve UserData
            selectedNode = app.Tree.SelectedNodes;
            if isempty(selectedNode)
                return; % Exit if no node is selected
            end

            % Specify the row index and retrieve the column from UserData
            rowIndex = 4;
            columnIndex = selectedNode.UserData;

            % Update the data table with the selected value
            app.Data{rowIndex, columnIndex} = {selectedValue};

            % Write the updated table back to an Excel file
            writeFullExcel(app, 'TMVMatlab.xlsx');

            % Enable or disable the text box based on the dropdown selection
            if strcmp(selectedValue, 'Y')
                app.PlannedChangeEditField.Enable = 'on'; % Enable the text box if 'Y' is selected
                app.MSerialEditField.Enable = 'on'; % Enable the text box if 'Y' is selected
                app.NamesEditField.Enable = 'on'; % Enable the text box if 'Y' is selected
            else
                app.PlannedChangeEditField.Enable = 'off'; % Disable the text box for other selections
                app.MSerialEditField.Enable = 'off'; % Disable the text box for other selections
                app.NamesEditField.Enable = 'off'; % Disable the text box for other selections
            end
        end

        % Value changed function: MSerialEditField
        function MSerialEditFieldValueChanged(app, event)
            % Get the input text from the MSerial edit field
            inputText = app.MSerialEditField.Value;

            % Ensure inputText is a single string
            if iscell(inputText)
                inputText = inputText{1}; % Extract the first element if it's a cell
            elseif isstring(inputText)
                inputText = char(inputText); % Convert to character array if a string
            end

            % Get the selected node from the tree to retrieve UserData
            selectedNode = app.Tree.SelectedNodes;
            if isempty(selectedNode)
                return; % Exit if no node is selected
            end

            % Specify the row index and retrieve the column index from UserData
            rowIndex = 6;
            columnIndex = selectedNode.UserData;

            % Update the data table with the input text
            app.Data{rowIndex, columnIndex} = {inputText};

            % Write the updated table back to an Excel file
            writeFullExcel(app, 'TMVMatlab.xlsx');
        end

        % Value changed function: CalibratedDropDown
        function CalibratedDropDownValueChanged(app, event)
            % Get the selected value from the calibrated dropdown
            selectedValue = app.CalibratedDropDown.Value;

            % Retrieve the currently selected node from the tree
            selectedNode = app.Tree.SelectedNodes;
            if isempty(selectedNode)
                return; % Exit the function if no node is selected
            end

            % Specify the row and retrieve the column index from UserData
            rowIndex = 7;
            columnIndex = selectedNode.UserData;

            % Update the data table with the selected value
            app.Data{rowIndex, columnIndex} = {selectedValue};

            % Write the updated table back to an Excel file
            writeFullExcel(app, 'TMVMatlab.xlsx');

            % Enable or disable the calibration edit field based on the dropdown selection
            if strcmp(selectedValue, 'Y')
                app.CalibrationEditField.Enable = 'on'; % Enable the text box if 'Y' is selected
            else
                app.CalibrationEditField.Enable = 'off'; % Disable the text box for any other selection
            end
        end

        % Value changed function: CalibrationEditField
        function CalibrationEditFieldValueChanged(app, event)
            % Get the input text from the calibration edit field
            inputText = app.CalibrationEditField.Value;

            % Ensure inputText is a single string
            if iscell(inputText)
                inputText = inputText{1}; % Extract the first element if it's a cell
            elseif isstring(inputText)
                inputText = char(inputText); % Convert to character array if it's a string
            end

            % Get the selected node from the tree to retrieve UserData
            selectedNode = app.Tree.SelectedNodes;
            if isempty(selectedNode)
                return; % Exit the function if no node is selected
            end

            % Specify the row index and retrieve the column index from UserData
            rowIndex = 8;
            columnIndex = selectedNode.UserData;

            % Update the data table with the input text
            app.Data{rowIndex, columnIndex} = {inputText};

            % Write the updated table back to an Excel file
            writeFullExcel(app, 'TMVMatlab.xlsx');
        end

        % Value changed function: IQDropDown
        function IQDropDownValueChanged(app, event)
            % Get the selected value from the dropdown
            selectedValue = app.IQDropDown.Value;

            % Retrieve the currently selected node from the tree
            selectedNode = app.Tree.SelectedNodes;
            if isempty(selectedNode)
                return; % Exit the function if no node is selected
            end

            % Specify the row index and retrieve the column index from UserData
            rowIndex = 9;
            columnIndex = selectedNode.UserData;

            % Update the data table with the selected value
            app.Data{rowIndex, columnIndex} = {selectedValue};

            % Write the updated table back to an Excel file
            writeFullExcel(app, 'TMVMatlab.xlsx');

            % Enable or disable the text box based on the dropdown selection
            if strcmp(selectedValue, 'Y')
                app.IQReportEditField.Enable = 'on'; % Enable the text box if 'Y' is selected
            else
                app.IQReportEditField.Enable = 'off'; % Disable the text box for any other selection
            end
        end

        % Value changed function: IQReportEditField
        function IQReportEditFieldValueChanged(app, event)
            % Retrieve the value from the IQReportEditField
            inputText = app.IQReportEditField.Value;

            % Ensure inputText is a single string
            if iscell(inputText)
                inputText = inputText{1}; % Extract the first element if it's a cell
            elseif isstring(inputText)
                inputText = char(inputText); % Convert to character array if it's a string
            end

            % Get the currently node in the tree to retrieve UserData
            selectedNode = app.Tree.SelectedNodes;
            if isempty(selectedNode)
                return; % Exit the function if no node is selected
            end

            % Specify the row index and column name based on the selected node
            rowIndex = 10;
            columnIndex = selectedNode.UserData;

            % Update the data table with the new input text
            app.Data{rowIndex, columnIndex} = {inputText}; % Store inputText in the specified cell

            % Write the updated table back to the Excel file
            writeFullExcel(app, 'TMVMatlab.xlsx'); % Save changes to the Excel file
        end

        % Value changed function: NamesEditField
        function NamesEditFieldValueChanged(app, event)
            % Retrieve the value from the NamesEditField
            inputText = app.NamesEditField.Value;

            % Ensure inputText is a single string
            if iscell(inputText)
                inputText = inputText{1}; % Extract the first element if it's a cell
            elseif isstring(inputText)
                inputText = char(inputText); % Convert to character array if it's a string
            end

            % Get the currently selected node in the tree to retrieve UserData
            selectedNode = app.Tree.SelectedNodes;
            if isempty(selectedNode)
                return; % Exit the function if no node is selected
            end

            % Specify the row index and column name based on the selected node
            rowIndex = 11; % Fixed row index for updating data
            columnIndex = selectedNode.UserData; % Get the column index from UserData of the selected node

            % Update the data table with the new input text
            app.Data{rowIndex, columnIndex} = {inputText}; % Store inputText in the specified cell

            % Write the updated table back to the Excel file
            writeFullExcel(app, 'TMVMatlab.xlsx'); % Save changes to the Excel file
        end

        % Button pushed function: MSerialNumberInfo
        function MSerialNumberInfoButtonPushed(app, event)
            app.MSerialNumberInfo.ButtonPushedFcn = @(src, event) showMoreInfo(app);
        end

        function showMoreInfo(app)
            % Display a UI alert with more information
            uialert(app.UIFigure, 'Identify the fixturing/equipment/tooling/software associated with the test method, e.g. templates, solder pallets, vision systems, tweezers.', 'More Info', ...
                'Icon', 'none'); % Set icon to none to avoid warning symbol
        end

        % Callback function
        function ExitButtonPushed(app, event)
            delete(app); % Closes the app
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TMVChecklistPanel = uipanel(app.UIFigure);
            app.TMVChecklistPanel.Title = 'TMV Checklist';
            app.GridLayout = uigridlayout(app.TMVChecklistPanel);
            app.RevEditField = uieditfield(app.GridLayout, 'text');
            app.RevEditField.ValueChangedFcn = createCallbackFcn(app, @RevEditFieldValueChanged, true);
            app.RevEditField.Enable = 'off';
            app.OPEditField = uieditfield(app.GridLayout, 'text');
            app.OPEditField.ValueChangedFcn = createCallbackFcn(app, @OPEditFieldValueChanged, true);
            app.OPEditField.Enable = 'off';
            app.OPNumberLabel = uilabel(app.GridLayout);
            app.OPNumberLabel.Text = 'OP Number';
            app.RevLabel = uilabel(app.GridLayout);
            app.RevLabel.Text = 'Rev';
            app.StepLabel = uilabel(app.GridLayout);
            app.StepLabel.Text = 'Step';
            app.PlannedChangeEditField = uieditfield(app.GridLayout, 'text');
            app.PlannedChangeEditField.ValueChangedFcn = createCallbackFcn(app, @PlannedChangeEditFieldValueChanged, true);
            app.PlannedChangeEditField.Enable = 'off';
            app.WhatistheplannedchangeLabel = uilabel(app.GridLayout);
            app.WhatistheplannedchangeLabel.Text = 'What is the planned change?';
            app.IsthereaplannedchangetothisTestMethodLabel = uilabel(app.GridLayout);
            app.IsthereaplannedchangetothisTestMethodLabel.Text = 'Is there a planned change to this Test Method?';
            app.PlannedChangeDropDown = uidropdown(app.GridLayout);
            app.PlannedChangeDropDown.Items = {'Y', 'N', 'N/A'};
            app.PlannedChangeDropDown.ValueChangedFcn = createCallbackFcn(app, @PlannedChangeDropDownValueChanged, true);
            app.PlannedChangeDropDown.Enable = 'off';
            app.PlannedChangeDropDown.Value = 'Y';
            app.MNumberSerialNumberLabel = uilabel(app.GridLayout);
            app.MNumberSerialNumberLabel.Text = 'M Number/Serial Number';
            app.IsthereaplannedchangetothisTestMethodLabel_2 = uilabel(app.GridLayout);
            app.IsthereaplannedchangetothisTestMethodLabel_2.Text = 'Identify the fixturing/equipment/tooling/software associated with the Test Method';
            app.MSerialEditField = uieditfield(app.GridLayout, 'text');
            app.MSerialEditField.ValueChangedFcn = createCallbackFcn(app, @MSerialEditFieldValueChanged, true);
            app.MSerialEditField.Enable = 'off';
            app.IsthereaplannedchangetothisTestMethodLabel_3 = uilabel(app.GridLayout);
            app.IsthereaplannedchangetothisTestMethodLabel_3.Text = 'Have these fixtures/equipment (e.g. Vision Systems) been calibrated?';
            app.CalibratedDropDown = uidropdown(app.GridLayout);
            app.CalibratedDropDown.Items = {'Y', 'N', 'N/A'};
            app.CalibratedDropDown.ValueChangedFcn = createCallbackFcn(app, @CalibratedDropDownValueChanged, true);
            app.CalibratedDropDown.Enable = 'off';
            app.CalibratedDropDown.Value = 'Y';
            app.CalibrationNumberLabel = uilabel(app.GridLayout);
            app.CalibrationNumberLabel.Text = 'Calibration Number';
            app.CalibrationEditField = uieditfield(app.GridLayout, 'text');
            app.CalibrationEditField.ValueChangedFcn = createCallbackFcn(app, @CalibrationEditFieldValueChanged, true);
            app.CalibrationEditField.Enable = 'off';
            app.IQReportNumberLabel = uilabel(app.GridLayout);
            app.IQReportNumberLabel.Text = 'IQ Report Number';
            app.IsthereaplannedchangetothisTestMethodLabel_4 = uilabel(app.GridLayout);
            app.IsthereaplannedchangetothisTestMethodLabel_4.Text = 'Have these fixtures been qualified?';
            app.IQDropDown = uidropdown(app.GridLayout);
            app.IQDropDown.Items = {'Y', 'N', 'N/A'};
            app.IQDropDown.ValueChangedFcn = createCallbackFcn(app, @IQDropDownValueChanged, true);
            app.IQDropDown.Enable = 'off';
            app.IQDropDown.Value = 'Y';
            app.IQReportEditField = uieditfield(app.GridLayout, 'text');
            app.IQReportEditField.ValueChangedFcn = createCallbackFcn(app, @IQReportEditFieldValueChanged, true);
            app.IQReportEditField.Enable = 'off';
            app.NamesEditField = uieditfield(app.GridLayout, 'text');
            app.NamesEditField.ValueChangedFcn = createCallbackFcn(app, @NamesEditFieldValueChanged, true);
            app.NamesEditField.Enable = 'off';
            app.IsthereaplannedchangetothisTestMethodLabel_5 = uilabel(app.GridLayout);
            app.IsthereaplannedchangetothisTestMethodLabel_5.Text = 'Which Operators have been trained to this process step?';
            app.StepEditField = uieditfield(app.GridLayout, 'text');
            app.StepEditField.ValueChangedFcn = createCallbackFcn(app, @StepEditFieldValueChanged, true);
            app.StepEditField.Enable = 'off';
            app.MSerialNumberInfo = uibutton(app.GridLayout, 'push');
            app.MSerialNumberInfo.ButtonPushedFcn = createCallbackFcn(app, @MSerialNumberInfoButtonPushed, true);
            app.MSerialNumberInfo.Text = 'i';
            app.Station = uipanel(app.UIFigure);
            app.Station.Title = 'Station';
            app.Tree = uitree(app.UIFigure);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.InnerHHSNode = uitreenode(app.Tree);
            app.InnerHHSNode.Text = 'Inner-HHS';
            app.VerificationNode = uitreenode(app.InnerHHSNode);
            app.VerificationNode.Text = 'Verification';
            app.BlueLightNode = uitreenode(app.VerificationNode);
            app.BlueLightNode.Text = 'Blue Light';
            app.PETNode = uitreenode(app.Tree);
            app.PETNode.Text = 'PET';
            app.DimensionalNode = uitreenode(app.PETNode);
            app.DimensionalNode.Text = 'Dimensional';
            app.PETOverhangNode = uitreenode(app.DimensionalNode);
            app.PETOverhangNode.Text = 'PET Overhang';
            app.DistalTackNode = uitreenode(app.Tree);
            app.DistalTackNode.Text = 'Distal Tack';
            app.VisualNode = uitreenode(app.DistalTackNode);
            app.VisualNode.Text = 'Visual';
            app.PoorTackBondNode = uitreenode(app.VisualNode);
            app.PoorTackBondNode.Text = 'Poor Tack Bond';
            app.TipAttachNode = uitreenode(app.Tree);
            app.TipAttachNode.Text = 'Tip Attach';
            app.HHSE1DistanceNode = uitreenode(app.TipAttachNode);
            app.HHSE1DistanceNode.Text = 'HHS – E1 Distance';
            app.TipShapeNode = uitreenode(app.Tree);
            app.TipShapeNode.Text = 'Tip Shape';
            app.XDimensionalNode = uitreenode(app.TipShapeNode);
            app.XDimensionalNode.Text = '(25X) (Dimensional)';
            app.MBDistalTipNode = uitreenode(app.XDimensionalNode);
            app.MBDistalTipNode.Text = 'MB-Distal Tip';
            app.MarkerbandBubbleNode = uitreenode(app.XDimensionalNode);
            app.MarkerbandBubbleNode.Text = 'Markerband Bubble';
            app.XVisualNode = uitreenode(app.TipShapeNode);
            app.XVisualNode.Text = '(60X) (Visual)';
            app.TipLaminationNode = uitreenode(app.XVisualNode);
            app.TipLaminationNode.Text = 'Tip Lamination';
            app.IMBondNode = uitreenode(app.Tree);
            app.IMBondNode.Text = 'IM Bond';
            app.VerificationNode_2 = uitreenode(app.IMBondNode);
            app.VerificationNode_2.Text = 'Verification';
            app.CorrectBondaidIMBond = uitreenode(app.VerificationNode_2);
            app.CorrectBondaidIMBond.Text = 'Correct Bondaid';
            app.EXJBondNode = uitreenode(app.Tree);
            app.EXJBondNode.Text = 'EXJ Bond';
            app.VerificationNode_3 = uitreenode(app.EXJBondNode);
            app.VerificationNode_3.Text = 'Verification';
            app.CorrectBondaidEXJBond = uitreenode(app.VerificationNode_3);
            app.CorrectBondaidEXJBond.Text = 'Correct Bondaid';
            app.CorrectInnerMemberLengthNode = uitreenode(app.VerificationNode_3);
            app.CorrectInnerMemberLengthNode.Text = 'Correct Inner Member Length';
            app.HoopingNode = uitreenode(app.Tree);
            app.HoopingNode.Text = 'Hooping';
            app.PatencyNode = uitreenode(app.HoopingNode);
            app.PatencyNode.Text = 'Patency';
            app.IMShaftNode = uitreenode(app.HoopingNode);
            app.IMShaftNode.Text = 'IM Shaft';
            app.VisualNode_2 = uitreenode(app.IMShaftNode);
            app.VisualNode_2.Text = 'Visual';
            app.KinkedIMShaftNode = uitreenode(app.VisualNode_2);
            app.KinkedIMShaftNode.Text = 'Kinked IM Shaft';
            app.DistalSectionNode = uitreenode(app.HoopingNode);
            app.DistalSectionNode.Text = 'Distal Section';
            app.VisualNode_3 = uitreenode(app.DistalSectionNode);
            app.VisualNode_3.Text = 'Visual';
            app.SurfaceImperfections = uitreenode(app.VisualNode_3);
            app.SurfaceImperfections.Text = 'Surface Scratches & Fracture lines, Fillet Degradation';
            app.DimensionalNode_2 = uitreenode(app.DistalSectionNode);
            app.DimensionalNode_2.Text = 'Dimensional';
            app.ElectrodeSpacingNode = uitreenode(app.DimensionalNode_2);
            app.ElectrodeSpacingNode.Text = 'Electrode Spacing';
            app.FilletSeparationNode = uitreenode(app.DimensionalNode_2);
            app.FilletSeparationNode.Text = 'Fillet Separation';
            app.FinalInspectNode = uitreenode(app.Tree);
            app.FinalInspectNode.Text = 'Final Inspect';
            app.VisualImperfections = uitreenode(app.FinalInspectNode);
            app.VisualImperfections.Text = 'Visual Imperfections';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TMVApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end